classdef V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Label_3             matlab.ui.control.Label
        PlayOriginalButton  matlab.ui.control.Button
        Label_2             matlab.ui.control.Label
        next                matlab.ui.control.Button
        reset               matlab.ui.control.Button
        exp                 matlab.ui.control.DropDown
        LabelDropDown       matlab.ui.control.Label
        Label               matlab.ui.control.Label
        UIAxes6             matlab.ui.control.UIAxes
        UIAxes5             matlab.ui.control.UIAxes
        UIAxes4             matlab.ui.control.UIAxes
        UIAxes3             matlab.ui.control.UIAxes
        UIAxes2             matlab.ui.control.UIAxes
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Item;
        counter = 0;
        y;Fs; n ;ty;fn;value;
        fs_y ;fshift;
        freq_y;
        fft_y ;
        band_limit  ;
        plotting_filter  ;
        filterr ;
        filtered_signal;
        plot_filtered ;
        time_signal ;
        L ;
        freq ;
        SSB_f ;
        SSB_t ;
        t ;
        Demodulated_SSB_t;
        Demodulated_SSB_f;
        fc;
        m_t ;
        c_t ;
        DSB_SC ;
        F_DSB_SC ;
        Ac
        DSB_TC;
        F_DSB_TC;
        b;a;
        SSB_practical_t ;
        Demod_SSB_butter_t ;
        Demod_SSB_butter_f ;
        SSB_SNR0 ;
        demod_t_SNR0;
        demod_f_SNR0 ;
        SSB_SNR10 ;
        demod_t_SNR10 ;
        demod_f_SNR10 ;
        SSB_SNR30 ;
        demod_t_SNR30;
        demod_f_SNR30;
        SSB_t_TC ;
        envelopeSSB_TC ;
        fvec;
        env_DSB_SC;
        env_DSB_TC;
        db_vec;
        out_db;
        env_db ;
        env_0db;
        env_10db;
        env_30db;
        demodulated;
        demod_freq_domain ;
        s1;s2;s3;
        fc_error;c_t_freq_error;demodulated_with_freq_error;freq_error;
        c_t_phase_error;demodulated_with_phase_error;
        filter;
        nbfm;
    end
    methods
    function rest(app, v)
        if strcmp(app.exp.Value, 'DSB')
                app.Item = 1;
        end
            if strcmp(app.exp.Value, 'SSB')
                app.Item = 2;
            end
            if strcmp(app.exp.Value, 'FM')
                app.Item = 3;
            end
            app.Label_2.Text='';
            app.next.Text= 'Procedure 1';
            [app.y, app.Fs] = audioread('eric.wav');
            app.n = length(app.y);
            app.ty = app.n/app.Fs;
            app.fn = app.Fs/2;
            app.counter=v;
            cla(app.UIAxes, 'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            cla(app.UIAxes4,'reset');
            cla(app.UIAxes5,'reset');
            cla(app.UIAxes6,'reset');
            app.UIAxes.Visible   = 'off';
            app.UIAxes2.Visible  = 'off';
            app.UIAxes3.Visible  = 'off';
            app.UIAxes4.Visible  = 'off';
            app.UIAxes5.Visible  = 'off';
            app.UIAxes6.Visible  = 'off';
    end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Visible   = 'off';
            app.UIAxes2.Visible  = 'off';
            app.UIAxes3.Visible  = 'off';
            app.UIAxes4.Visible  = 'off';
            app.UIAxes5.Visible  = 'off';
            app.UIAxes6.Visible  = 'off';
        end

        % Clicked callback: exp
        function expClicked(app, event)
            rest(app,1);
        end

        % Value changed function: exp
        function expValueChanged(app, event)
            app.value = app.exp.Value;
            app.Item = app.value;
        end

        % Button pushed function: next
        function nextPushed(app, event)
            if app.counter == 1
                rest(app,1);
            end
            app.next.Text=sprintf("Procedure %d", app.counter);
            if app.Item == 1
                DSB(app);
                if (app.counter == 9)
                    app.Label_2.Text='Experiment Completed!!!';
                    app.counter=0;
                else
                    app.Label_2.Text='';
                end
            end
            if app.Item == 2
                SSB(app);
                if (app.counter == 12)
                    app.Label_2.Text='Experiment Completed!!!';
                    app.counter=0;
                else
                    app.Label_2.Text='';
                end 
            end
            if app.Item == 3
                FM(app);
                if (app.counter == 5)
                    app.Label_2.Text='Experiment Completed!!!';
                    app.counter=0;
                else
                    app.Label_2.Text='';
                end 
            end
            app.counter = app.counter +1;
            app.next.Text=sprintf("Procedure %d", app.counter);
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            rest(app,1);
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginal(app, event)
            [app.y, app.Fs] = audioread('eric.wav');
            app.Label_3.Text='Playing sound please wait.....';
            sound(app.y, app.Fs);
            pause(8);
            app.Label_3.Text='';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Modulator & Demodulator';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Experiment';
            app.exp = uidropdown(app.UIFigure);
            app.exp.Items = {'', 'DSB', 'SSB', 'FM'};
            app.exp.ValueChangedFcn = createCallbackFcn(app, @expValueChanged, true);
            app.exp.ClickedFcn = createCallbackFcn(app, @expClicked, true);
            app.exp.Value = '';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'Reset';
            app.next = uibutton(app.UIFigure, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextPushed, true);
            app.next.Text = 'Procedure 1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginal, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end